classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        GridLayout         matlab.ui.container.GridLayout
        LeftPanel          matlab.ui.container.Panel
        SelectimageButton  matlab.ui.control.Button
        RightPanel         matlab.ui.container.Panel
        UIAxes             matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectimageButton
        function SelectimageButtonPushed(app, event)
            startingFolder = pwd;
            defaultFileName = fullfile(startingFolder, '*.*');
            [baseFileName, folder] = uigetfile(defaultFileName, 'Select a file');
            if baseFileName == 0
                  % User clicked the Cancel button.
                  return;
             end
            %fullFileName = fullfile(folder, baseFileName);
            %valset = fullFileName;
            %disp(valset);
            load 'my_model.mat'
            
            imds = imageDatastore('.\poster_downloads');
            
            num_images = 7635;
            dataset = zeros(num_images, 2700);
            for i = 1:num_images
                img = readimage(imds, i);
                img = imresize(img, [30, 30]);
                try
                    img = reshape(img, [2700, 1]);
                    dataset(i, :) = img;
                catch
                    disp('INVALID CATCH');
                end
            end

            
            valset = imread(fullfile(folder, baseFileName));
            %valset = imread('C:\Users\gurus\Desktop\Movie_poster\Validation\2.3_82815.jpg');
            valset = imresize(valset, [30, 30]);
            valset = reshape(valset, [1, 2700]);
            valset = double(valset);
            encoded_images_val = encode(autoenc, valset');
            encoded_images_val = encoded_images_val';
            num_images_val = size(valset);
            num_images_val = num_images_val(1);
            num_images_train = size(dataset);
            num_images_train = num_images_train(1);
            num_images_train
            errors = zeros(num_images_train, 1);
            selected_val_image = 1;


            for i = 1:num_images_train
                errors(i,1) = sum( (encoded_image(i,:) - encoded_images_val(selected_val_image,:)).^2 );
            end
            
            [x , best_image_index] = min(errors);
            best_image_index
            img = readimage(imds, best_image_index);
            %imshow(img);
            %imshow(myImage); 
            
            imshow(img, 'parent', app.UIAxes)
            
        end

        % Callback function
        function ImageClicked(app, event)
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SelectimageButton = uibutton(app.LeftPanel, 'push');
            app.SelectimageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectimageButtonPushed, true);
            app.SelectimageButton.Text = 'Select image';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, {'Recommended Movie'; ''})
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end